classdef start_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChoosetheDatasetFolderLabel   matlab.ui.control.Label
        src                           matlab.ui.control.Label
        ChooseFolderButton            matlab.ui.control.Button
        ChoosetheBackgroundImageVideoLabel  matlab.ui.control.Label
        bg                            matlab.ui.control.Label
        ChooseFileButton              matlab.ui.control.Button
        StartingFrameEditFieldLabel   matlab.ui.control.Label
        start                         matlab.ui.control.NumericEditField
        ModeDropDownLabel             matlab.ui.control.Label
        mode                          matlab.ui.control.DropDown
        ChoosetheOutputFolderLabel    matlab.ui.control.Label
        dst                           matlab.ui.control.Label
        ChooseFolderButton_2          matlab.ui.control.Button
        ImageR                        matlab.ui.control.Image
        ImageL                        matlab.ui.control.Image
        ImageResult                   matlab.ui.control.Image
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        OutputFileNameEditFieldLabel  matlab.ui.control.Label
        OutputFileNameEditField       matlab.ui.control.EditField
        aviLabel                      matlab.ui.control.Label
        SwitchLabel                   matlab.ui.control.Label
        displayImagesRealtime         matlab.ui.control.Switch
        LoopSwitchLabel_2             matlab.ui.control.Label
        loop                          matlab.ui.control.Switch
        StoreSwitch_2Label            matlab.ui.control.Label
        StoreSwitch_2                 matlab.ui.control.Switch
    end

    
    properties (Access = public)
        shouldStop % Flag for stopping
        paused % Flag for pausing
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            % Choose the input folder and set the text of the source label
            inputFolder = uigetdir;
            if ischar(inputFolder)
                app.src.Text = inputFolder;
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            % Choose the background file and set the text of the bg label
            [bgFile, path] = uigetfile({'*.png;*.mp4;*.jpg;*.avi;';'*.*'}, ...
                'Please load the background photo or video file');
            if bgFile ~= 0
                app.bg.Text = fullfile(path, bgFile);
            end
        end

        % Button pushed function: ChooseFolderButton_2
        function ChooseFolderButton_2Pushed(app, event)
            % Choose the output folder and set the text of the dst label
            outputFolder = uigetdir;
            if ischar(outputFolder)
                app.dst.Text = outputFolder;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Validate inputs
            if app.src.Text == "" || app.src.Text == "No folder is selected yet." || ...
                    (app.mode.Value == "substitute" && ...
                        (app.bg.Text == "" || app.bg.Text == "No file is selected yet.")) || ...
                    (app.StoreSwitch_2.Value == "On" && ...
                        (app.dst.Text == "" || app.dst.Text == "No folder is selected yet." || ...
                         app.OutputFileNameEditField.Value == ""))
                warndlg("Please make sure that you have selected all " ...
                    + "necessary files and folders.");
            elseif app.start.Value < 1
                warndlg("Start should be at least 1.");
            else
                % Enable/Disable necessary buttons
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.PauseButton.Enable = 'on';
                app.shouldStop = false;
                app.paused = false;
                challenge;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Enable/Disable necessary buttons
            app.shouldStop = true;
            app.paused = false;
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            
            % Display message if a video is saved.
            if app.StoreSwitch_2.Value == "On"
                msgbox("Video is saved to the target directory!");
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Arrange the button label intiutively
            if app.paused
                app.paused = false;
                app.PauseButton.Text = "Pause";
            else
                app.paused = true;
                app.PauseButton.Text = "Continue";
            end
        end

        % Value changed function: mode
        function modeValueChanged(app, event)
            % Enable/Disable the background image/video choosing components
            % accordingly
            value = app.mode.Value;
            if value == "substitute"
                app.ChooseFileButton.Enable = 'on';
                app.ChoosetheBackgroundImageVideoLabel.Enable = 'on';
                app.bg.Enable = 'on';
            else
                app.ChooseFileButton.Enable = 'off';
                app.ChoosetheBackgroundImageVideoLabel.Enable = 'off';
                app.bg.Enable = 'off';            
            end
        end

        % Value changed function: StoreSwitch_2
        function StoreSwitch_2ValueChanged(app, event)
            % Enable/Disable the output folder choosing components
            % accordingly
            value = app.StoreSwitch_2.Value;
            if value == "On"
                app.ChooseFolderButton_2.Enable = 'on';
                app.ChoosetheOutputFolderLabel.Enable = 'on';
                app.OutputFileNameEditField.Enable = 'on';
                app.OutputFileNameEditFieldLabel.Enable = 'on';
                app.dst.Enable = 'on';
                app.aviLabel.Enable = 'on';
            else
                app.ChooseFolderButton_2.Enable = 'off';
                app.ChoosetheOutputFolderLabel.Enable = 'off';
                app.OutputFileNameEditField.Enable = 'off';
                app.OutputFileNameEditFieldLabel.Enable = 'off';
                app.dst.Enable = 'off';
                app.aviLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosetheDatasetFolderLabel = uilabel(app.UIFigure);
            app.ChoosetheDatasetFolderLabel.Text = 'Choose the Dataset Folder';
            app.src = uilabel(app.UIFigure);
            app.src.Text = 'No folder is selected yet.';
            app.ChooseFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.ChoosetheBackgroundImageVideoLabel = uilabel(app.UIFigure);
            app.ChoosetheBackgroundImageVideoLabel.Enable = 'off';
            app.ChoosetheBackgroundImageVideoLabel.Text = 'Choose the Background Image / Video';
            app.bg = uilabel(app.UIFigure);
            app.bg.Enable = 'off';
            app.bg.Text = 'No file is selected yet.';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Enable = 'off';
            app.ChooseFileButton.Text = 'Choose File';
            app.StartingFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartingFrameEditFieldLabel.Text = 'Starting Frame';
            app.start = uieditfield(app.UIFigure, 'numeric');
            app.start.Value = 1;
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.mode = uidropdown(app.UIFigure);
            app.mode.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.mode.ValueChangedFcn = createCallbackFcn(app, @modeValueChanged, true);
            app.mode.Value = 'foreground';
            app.ChoosetheOutputFolderLabel = uilabel(app.UIFigure);
            app.ChoosetheOutputFolderLabel.Enable = 'off';
            app.ChoosetheOutputFolderLabel.Text = 'Choose the Output Folder';
            app.dst = uilabel(app.UIFigure);
            app.dst.Enable = 'off';
            app.dst.Text = 'No folder is selected yet.';
            app.ChooseFolderButton_2 = uibutton(app.UIFigure, 'push');
            app.ChooseFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButton_2Pushed, true);
            app.ChooseFolderButton_2.Enable = 'off';
            app.ChooseFolderButton_2.Text = 'Choose Folder';
            app.ImageR = uiimage(app.UIFigure);
            app.ImageL = uiimage(app.UIFigure);
            app.ImageResult = uiimage(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.OutputFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFileNameEditFieldLabel.Enable = 'off';
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.OutputFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFileNameEditField.Enable = 'off';
            app.OutputFileNameEditField.Value = 'output';
            app.aviLabel = uilabel(app.UIFigure);
            app.aviLabel.Enable = 'off';
            app.aviLabel.Text = '.avi';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = {'I would like the images to be shown in real time and '; 'it is OK for me if this makes the process a bit slower.'};
            app.displayImagesRealtime = uiswitch(app.UIFigure, 'slider');
            app.LoopSwitchLabel_2 = uilabel(app.UIFigure);
            app.LoopSwitchLabel_2.Text = 'Loop';
            app.loop = uiswitch(app.UIFigure, 'slider');
            app.StoreSwitch_2Label = uilabel(app.UIFigure);
            app.StoreSwitch_2Label.Text = 'Store';
            app.StoreSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.StoreSwitch_2.ValueChangedFcn = createCallbackFcn(app, @StoreSwitch_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end